classdef PPT_DoAnCuoiKy_20200040_TranNgocHuy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        PhuongPhap            matlab.ui.control.DropDown
        PhngPhpDropDownLabel  matlab.ui.control.Label
        EditField_gxFunc      matlab.ui.control.EditField
        gxEditFieldLabel      matlab.ui.control.Label
        ThcthiButton          matlab.ui.control.Button
        EditField_slLap       matlab.ui.control.EditField
        slnlpEditFieldLabel   matlab.ui.control.Label
        EditField_KQua        matlab.ui.control.EditField
        ktquEditFieldLabel    matlab.ui.control.Label
        EditField_epsilon     matlab.ui.control.EditField
        epsilonLabel          matlab.ui.control.Label
        EditField_B           matlab.ui.control.EditField
        bEditFieldLabel       matlab.ui.control.Label
        EditField_A           matlab.ui.control.EditField
        aEditFieldLabel       matlab.ui.control.Label
        EditField_Func        matlab.ui.control.EditField
        fxEditFieldLabel      matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ThcthiButton
        function ThcthiButtonPushed(app, event)
            maxIter = 20; %Số lần lặp tối đa
            
            % Kiểm tra xem có ô nào rỗng không
            if isempty(app.PhuongPhap.Value)
                uialert(app.UIFigure, 'Chưa chọn phương pháp', 'Lỗi');
                return;
            end
            
            % Kiểm tra ô g(x) va f(x) có rỗng hoac không nhap dung dinh
            % dang hay khong?
            if strcmp(app.PhuongPhap.Value, 'Lặp')
                try
                    g = str2func(['@(x)', app.EditField_gxFunc.Value]);

                catch
                    uialert(app.UIFigure, 'NNhập sai định dạng (function g(x)) hoặc đang rỗng!', 'Lỗi');
                    return;
                end
            else
                try
                    f =  str2func(['@(x)', app.EditField_Func.Value]);

                catch
                    uialert(app.UIFigure, 'Nhập sai định dạng (function f(x)) hoặc đang rỗng!', 'Lỗi');
                    return;
                end
            end

            % Gán Các Value Từ Text Field Vào Các Variable
            %Kiem tra co nhap dung dinh dang f(x) khong

            % Kiem tra co nhap dung dinh dang a va b hoặc đang rỗng không
            try
                a = str2double(app.EditField_A.Value);
                if isnan(a)
                    goto catch;
                end
            catch
                uialert(app.UIFigure, 'Nhập sai định dạng (a (integer)) hoặc đang rỗng!', 'Lỗi');
                return;
            end

            try
                b = str2double(app.EditField_B.Value);
                if isnan(b)
                    goto catch;
                end
            catch
                uialert(app.UIFigure, 'Nhập sai định dạng (b (integer)) hoặc đang rỗng!', 'Lỗi');
                return;
            end
            
            % Kiểm tra có nhập đúng định dạng epsilon hoặc đang rỗng không?
            try
                epsilon = str2double(app.EditField_epsilon.Value);
                if isnan(epsilon)
                    goto catch;
                end
            catch
                uialert(app.UIFigure, 'Nhập sai định dạng (epsilon (integer)) hoặc đang rỗng!', 'Lỗi');
                return;
            end

            % kiểm tra khoảng phân ly có hợp lệ hay không?
            if a >= b
                app.EditField_KQua.Value = 'NaN';
                app.EditField_slLap.Value = 'NaN';
                message = 'Khoảng phân ly không hợp lệ hoặc không tồn tại khoảng phân ly với các giá trị a và b';
                uialert(app.UIFigure, message, 'Lỗi');
                return;
            end

            % kiểm tra trong khoảng phân ly có nghiệm hay không?
            if strcmp(app.PhuongPhap.Value, 'Lặp')
%                 if sign(g(a)) == sign(g(b))
%                     app.EditField_KQua.Value = 'NaN';
%                     app.EditField_slLap.Value = 'NaN';
%                     message = 'Khoảng phân ly không chứa nghiệm hoặc chứa một số nghiệm chẵn. Vui lòng chọn khoảng phân ly khác.';
%                     uialert(app.UIFigure, message, 'Lỗi');
%                     return;
%                 end
            elseif sign(f(a)) == sign(f(b))
                app.EditField_KQua.Value = 'NaN';
                app.EditField_slLap.Value = 'NaN';
                message = 'Khoảng phân ly không chứa nghiệm hoặc chứa một số nghiệm chẵn. Vui lòng chọn khoảng phân ly khác.';
                uialert(app.UIFigure, message, 'Lỗi');
                return;
            end
    

            % Chọn hàm để function của phương pháp nào để giải phương trình
            switch app.PhuongPhap.Value
                case 'Chia đôi'
                    [root, iteration] = PhuongPhapChiaDoi(f, a, b, epsilon);

                case 'Lặp'
                    [root, iteration, message] = PhuongPhapLap(g, a, b, epsilon, maxIter);
                    if ~isempty(message)
                        uialert(app.UIFigure, message, 'Lỗi');
                        return;
                    end
                    

                case 'Newton'
                    [root, iteration] = PhuongPhapNewton(f, a, epsilon, maxIter);
            end

            app.EditField_KQua.Value = num2str(root);
            app.EditField_slLap.Value = num2str(iteration);

            % Vẽ đồ thị
            x = linspace(a/3, b*3, 1000);
            if strcmp(app.PhuongPhap.Value, 'Lặp')
                y = arrayfun(g, x);

            else
                y = arrayfun(f, x);
            end
            plot(app.UIAxes, x, y, 'b-', 'LineWidth', 1.5);
            grid(app.UIAxes, 'on');
        end

        % Value changed function: PhuongPhap
        function PhuongPhapValueChanged(app, event)
          selectedOption = app.PhuongPhap.Value;
        
            % Kiểm tra giá trị của dropdown và ẩn/hiển thị EditField dựa trên điều kiện
            if strcmp(selectedOption, 'Lặp') % Thay 'Option1' bằng giá trị của phần tử bạn muốn kiểm tra
                app.EditField_gxFunc.Visible = 'on'; % Ẩn EditField
                app.gxEditFieldLabel.Visible = 'on';

                app.EditField_Func.Enable = "off";

                
            else
                app.EditField_gxFunc.Visible = 'off'; % Hiển thị EditField
                app.gxEditFieldLabel.Visible = 'off';

                app.EditField_Func.Enable = "on";
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Đồ thị')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.fxEditFieldLabel = uilabel(app.UIFigure);
            app.fxEditFieldLabel.Text = 'f(x)';
            app.EditField_Func = uieditfield(app.UIFigure, 'text');
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a: ';
            app.EditField_A = uieditfield(app.UIFigure, 'text');
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b: ';
            app.EditField_B = uieditfield(app.UIFigure, 'text');
            app.epsilonLabel = uilabel(app.UIFigure);
            app.epsilonLabel.Text = 'epsilon';
            app.EditField_epsilon = uieditfield(app.UIFigure, 'text');
            app.ktquEditFieldLabel = uilabel(app.UIFigure);
            app.ktquEditFieldLabel.Text = 'kết quả';
            app.EditField_KQua = uieditfield(app.UIFigure, 'text');
            app.EditField_KQua.Editable = 'off';
            app.slnlpEditFieldLabel = uilabel(app.UIFigure);
            app.slnlpEditFieldLabel.Text = 'số lần lặp';
            app.EditField_slLap = uieditfield(app.UIFigure, 'text');
            app.EditField_slLap.Editable = 'off';
            app.ThcthiButton = uibutton(app.UIFigure, 'push');
            app.ThcthiButton.ButtonPushedFcn = createCallbackFcn(app, @ThcthiButtonPushed, true);
            app.ThcthiButton.Text = 'Thực thi';
            app.gxEditFieldLabel = uilabel(app.UIFigure);
            app.gxEditFieldLabel.Visible = 'off';
            app.gxEditFieldLabel.Text = 'g(x)';
            app.EditField_gxFunc = uieditfield(app.UIFigure, 'text');
            app.EditField_gxFunc.Visible = 'off';
            app.PhngPhpDropDownLabel = uilabel(app.UIFigure);
            app.PhngPhpDropDownLabel.Text = 'Phương Pháp';
            app.PhuongPhap = uidropdown(app.UIFigure);
            app.PhuongPhap.Items = {'', 'Chia đôi', 'Lặp', 'Newton'};
            app.PhuongPhap.ValueChangedFcn = createCallbackFcn(app, @PhuongPhapValueChanged, true);
            app.PhuongPhap.Value = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PPT_DoAnCuoiKy_20200040_TranNgocHuy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end